classdef Taki_game < matlab.apps.AppBase
    %TAKI_GAME Taki game
    %   This code executes the game card game taki.
    %   It can be played with 2-4 players, of which 1-3 can be automatic
    %   computer players.
    %   The app starts on the main menu, where one can choose the number of
    %   players and which are human.
    %   After starting the game, player 1 (human) can see his cards, the
    %   draw cards pile, and the card discard pile. He can play cards
    %   according to the rules of the game. After his turn next player will
    %   play if human. If it's a computer turn, it has 3 seconds delay in
    %   play.
    %   The game ends when one player finishes the cards in his hand. After
    %   the game ends, the app return to the main menu.
    %   Cards are called in the following order
    %   1- colour change card
    %   2- super taki
    %   Each colour is represented by a different multiplication of
    %   100(1-4):
    %   100-red, 200-green, 300-blue and  400-yellow
    %   Where the singles represent the non-colour identity:
    %   1-9 are the same number as they represents(2 is plus 2)
    %   10- stop 
    %   11- change direction
    %   12- plus 
    %   13- takie
    %   for example:  310- blue stop sign   403- yellow three
    %   

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        computerSwitch_4       matlab.ui.control.Switch
        computerSwitch_3Label  matlab.ui.control.Label
        computerSwitch_3       matlab.ui.control.Switch
        computerSwitch_2Label  matlab.ui.control.Label
        computerSwitch_2       matlab.ui.control.Switch
        computerSwitchLabel    matlab.ui.control.Label
        players_num            matlab.ui.control.Spinner
        numberofplayersLabel   matlab.ui.control.Label
        Start                  matlab.ui.control.Button
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        end_taki               matlab.ui.control.Button
        draw_pile              matlab.ui.control.Button
        top_card               matlab.ui.control.Button
        more_cards             matlab.ui.control.Button
        return_cards           matlab.ui.control.Button
        cards_view             matlab.ui.container.ButtonGroup
    end

    
    properties (Access = private)
        number_of_players % Number of active players
        start_hand % How many cards to start with
        players % cells of class player
        direction % 1 or -1, indicating where next player is
        current_player % active player
        number_of_twos % a counter in case a +2 is in play
        deck % the draw pile, what is left is pile
        card_on_top % the current last disposed card
        taki_card % indicating if there's a taki card in play, by human player
        super_taki % indicating if human player playes a super taki
        main_menu % used to decide if the game has strated or not( false is game)
    end
    
    methods (Access = private)
        %%
        function card_made = card_create(~,card_button,card)
            % the function creats the card graphics, based on the identity
            % of variable card
            %   inputes:
            %           card- the identity of the card being made.
            %           card_button- the initail prporties of the button
            %           which will represnt the card
            %   outputs:
            %           a button component, which will look like a card
                colours=["red" "green" "blue" "yellow"];
                card_button.Text="";
                card_button.Icon="";
                card_button.FontName="Bauhaus 93";
                
                % following part will handle cards with a colour
                if card>=100
                    % handles regular number
                    card_button.FontSize=70;
                    card_button.Text = num2str(mod(card,100));

                    % a colour choosed, not real card
                    if mod(card,100)==0
                        card_button.Text=char(9679);
                        card_button.FontColor=colours(floor(card/100));
                    end

                    % handle plus two
                    if mod(card,100)==2
                        card_button.Text = append("2",char(8314)) ;
                    end

                    % handles stop card
                    if mod(card,100)==10
                        card_button.Text = char(8855) ;
                    end

                    % handle direction switch
                    if mod(card,100)==11
                        card_button.Text = char(11138) ;
                    end

                    % handles plus card
                    if mod(card,100)==12
                        card_button.Text = '+';
                    end

                    % handels taki card
                    if mod(card,100)==13
                        card_button.Text = 'TAKI';
                        card_button.WordWrap=true;
                        card_button.FontSize=50;
                    end
                    card_button.FontColor=colours(fix(card/100));
                
                % handels colour change
                elseif card==1
                    card_button.Text = '';
                    card_button.Icon="change_color_logo.png";
                    card_button.IconAlignment="center";

                % handels super taki
                elseif card==2
                    card_button.Text = '';
                    card_button.Icon="super_taki_logo.png";
                    card_button.IconAlignment="center";
                end
                card_made=card_button;
                
        end

        %%
        function create_hand(app)
            % this function handels the creation of cards of the current
            % player
            % input:
            %       the app, use cards_view, players and current player

            app.cards_view.Position(1)=1;
            buttons = app.cards_view.Children;

            % removes current cards
            for i = 1:length(buttons)
              delete(buttons(i));
            end
            % create a no use card, unshown, so toggle will not show
            noneed_card=uitogglebutton(app.cards_view);
            noneed_card.Tag="null";
            noneed_card.Visible="off";

            % cards creation
            for i = 1:length(app.players{app.current_player}.cards)
                
                cardButton(i) = uitogglebutton(app.cards_view);
                cardButton(i) = card_create(app,cardButton(i),app.players{app.current_player}.cards(i));
                cardButton(i).Position=[80*(i-1),1,80,131];
                cardButton(i).Tag=num2str(app.players{app.current_player}.cards(i));
            end
        end
        %%

        function player_colour_change(app)
            % function that change current hand to colour selection mode
            % letting the player decide the colour in play
            % input:
            %       app- using mainly cards_view
            buttons = app.cards_view.Children;

            % delete current cards in hand
            for i = 1:length(buttons)
                delete(buttons(i));
            end
            
            % create a no use card, unshown, so toggle will not show
            noneed_card=uitogglebutton(app.cards_view);
            noneed_card.Tag="null";
            noneed_card.Visible="off";

            %card creation
            for i = 1:4
                cardButton(i) = uitogglebutton(app.cards_view);
                cardButton(i).Position=[80*(i-1),1,80,131];
                cardButton(i)= card_create(app,cardButton(i),i*100);
                cardButton(i).Tag=num2str(i*100);
            end
        end
        %%

        function card_was_choosen(app)
            % this function exucutes the affect of all cards, both for human
            % and non human players
            % input:
            %       app- use lots of app attributes

            % handles change colour card
            if app.card_on_top==1
                % if player is human
                if app.players{app.current_player}.is_real==true
                    player_colour_change(app);
                    return
                % if player is computer
                elseif app.players{app.current_player}.is_real==false
                    pause(3);
                    if any(app.players{app.current_player}.cards>100)
                        app.card_on_top=mode(fix(app.players{app.current_player}.cards/100))*100;
                    else
                        colour_rand=randi([1 4]); %randomizes colour in case of no colour cards in hand
                        app.card_on_top=colour_rand*100;
                    end
                    app.top_card=card_create(app,app.top_card,app.card_on_top);
                end
            end

            % if card is taki or super taki
            if mod(app.card_on_top,100)==13 || app.card_on_top==2

                % if human player
                if app.players{app.current_player}.is_real
                    if app.card_on_top==2
                        player_colour_change(app);
                        app.super_taki=true;
                    else
                        app.taki_card=true;
                        app.end_taki.Visible="on";
                    end
                    return
                end

                % if computer
                if app.players{app.current_player}.is_real==false

                    % choose colour when card is super taki
                    if app.card_on_top==2
                        pause(3)
                        if any(app.players{app.current_player}.cards>100)
                            app.card_on_top=(mode(fix(app.players{app.current_player}.cards(find(app.players{app.current_player}.cards>=100))/100))*100)+13;
                        end
                        if app.card_on_top<99
                            colour_rand=randi([1 4]); %randomizes colour in case of no colour cards in hand
                            app.card_on_top=(colour_rand*100)+13;
                        end
                        app.top_card=card_create(app,app.top_card,app.card_on_top);
                        num_cards_disp(app);
                    end

                    possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top,"taki")); % checks what cards can be played by player
                    length_pos=length(possible_options); % how many cards will be played after taki card

                    % addes all possible option cards
                    for i=0:length_pos
                        if isempty(possible_options)
                            continue
                        end
                        pause(3);
                        app.card_on_top= min(possible_options);
                        % computer preffers to discard plus two as final card
                        if mod(app.card_on_top,100)==2
                            app.card_on_top=max(possible_options);
                        end
                        app.top_card=card_create(app,app.top_card,app.card_on_top);
                        % remove cards from possible option and from current player hand
                        possible_options(find(possible_options==app.card_on_top,1))=[];
                        app.players{app.current_player}.cards(find(app.players{app.current_player}.cards==app.card_on_top,1))=[];
                        num_cards_disp(app);
                        win_check(app)
                        if app.main_menu
                            return
                        end
                    end
                end
            end

            % handels stop card, skipping a turn 
            if mod(app.card_on_top,100)==10
                app.current_player = next_player(app.current_player,app.direction,app.number_of_players);
                current_turn(app)
            end
        
            % handles change direction 
            if mod(app.card_on_top,100)==11
                app.direction=app.direction*-1;
            end
        
            % if card is plus two
            if mod(app.card_on_top,100)==2
                app.number_of_twos=app.number_of_twos+1;
            end
       
            % if card is plus
            if mod(app.card_on_top,100)==12
                return
            else
                app.current_player = next_player(app.current_player,app.direction,app.number_of_players);
                current_turn(app)
            end
            
        end
%%

        function current_turn(app)
            % this function change the players labels, indicating which
            % player is currently playing
            % input:
            %       app- use lots of app attributes    

            app.Label.BackgroundColor="none";
            app.Label_2.BackgroundColor="none";
            app.Label_3.BackgroundColor="none";
            app.Label_4.BackgroundColor="none";
            if app.current_player==1
                app.Label.BackgroundColor="green";
            elseif app.current_player==2
                app.Label_2.BackgroundColor="green";
            elseif app.current_player==3
                app.Label_3.BackgroundColor="green";
            elseif app.current_player==4
                app.Label_4.BackgroundColor="green";
            end
        end
        %%
        function computer_choose(app)
            % this function handels the computer turn, choosing an
            % approprite card to discard
            % input:
            %       app- use lots of app attributes

            pause(3)
            % checks which players can be currently played, based on card on top and current player hand.
            if app.number_of_twos==0
                possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top));   
            else 
                possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top,"two")); 
            end
            
            % handels if a card cant be played
            if isempty(possible_options)
                % handels if card on top is plus two, drawing the right amount of cards
                if mod(app.card_on_top,100)==2
                    for i=1:(2*app.number_of_twos)
                        [temp,app.deck]=pick_card(app.deck);
                        app.players{app.current_player}.cards=[app.players{app.current_player}.cards, temp];
                        app.number_of_twos=0;
                    end
                else
                    [temp,app.deck]=pick_card(app.deck);
                    app.players{app.current_player}.cards=[app.players{app.current_player}.cards, temp];
                end
                app.current_player = next_player(app.current_player,app.direction,app.number_of_players);
                current_turn(app)
                return
            % addes the highest number card in hand, not playing randomly
            else
                app.card_on_top= max(possible_options);
                app.players{app.current_player}.cards(find(app.players{app.current_player}.cards==app.card_on_top,1))=[];
                num_cards_disp(app)
                win_check(app)
                
            end

            app.top_card=card_create(app,app.top_card,app.card_on_top);
            % return to main menu of wins
            if app.main_menu
                return
            end
            card_was_choosen(app);

            % return to main menu of wins, again in case more cards were in play in card_was_choosen function
            if app.main_menu
                return
            end
            num_cards_disp(app)
        end
        %%
        
        function  non_human_turns(app)
            % handels the display and right functions for each non human
            % turn
            % input:
            %       app- use lots of app attributes

            app.cards_view.Enable="off";
            app.more_cards.Visible="off";
            app.return_cards.Visible="off";
            app.draw_pile.Enable="off";
            % goes through all non human players
            while ~app.players{app.current_player}.is_real
                computer_choose(app)
                if app.main_menu
                    return
                end
                num_cards_disp(app);
            end

            create_hand(app);
            app.cards_view.Enable="on";
            app.more_cards.Visible="on";
            app.return_cards.Visible="on";
            app.draw_pile.Enable="on";
        end
        %%

        function num_cards_disp(app)
            % this function displays the number of cards in each player
            % hand. will be shown on the apprprite label.
            % input:
            %       app- use the labels, and a few other attributes.


            app.Label.Text= ["cards: " length(app.players{1}.cards)];
            app.Label_2.Text= ["cards: " length(app.players{2}.cards)];
            if app.number_of_players>2
                app.Label_3.Text= ["cards: " length(app.players{3}.cards)];
                if app.number_of_players>3
                    app.Label_4.Text= ["cards: " length(app.players{4}.cards)];
                else
                    app.Label_4.Text="";
                end
            else
                app.Label_3.Text="";
                app.Label_4.Text="";
            end
        end
        %%

        function win_check(app)
            % checks if any of the players have won the game, if one did,
            % releses a txt massage indicating the winning player


            for i=1:app.number_of_players
                if isempty(app.players{i}.cards)
                    winner_msg = dialog('Position',[app.UIFigure.Position(3)/2-125,app.UIFigure.Position(4)/2-75,250,150],'Name','Game Over');
                    txt = uicontrol('Parent',winner_msg,'Style','text','Position',[20 80 210 40],'String',['WINNER: player ', num2str(i)] );
                    btn = uicontrol('Parent',winner_msg,'Position',[70 20 110 25],'String','exit to main menu','Callback','delete(gcf)');
                    app.draw_pile.Enable="off";
                    uiwait(winner_msg)
                    app.draw_pile.Enable="on";
                    app.main_menu= true;
                    screen_maker(app);
                    
                end
            end
        end
        %%
        function game_stratup(app)
            % after main menu, this function creats all variabels needed
            % for the game, and starts player1 turn
            % input:
            %       app- use lots of app attributes

            % give the properties values
            app.number_of_players=app.players_num.Value;
            app.start_hand=8;
            app.players=cell(1,app.number_of_players);
            app.direction= 1;
            app.current_player=1;
            app.number_of_twos=0;
            app.deck= new_deck;
            app.taki_card=false;
            app.super_taki=false;

            % creating all players based on active or non active switch
            real_maker=["Off",app.computerSwitch_2.Value, app.computerSwitch_3.Value,app.computerSwitch_4.Value];
            for i=1:app.number_of_players
                Player.number_of_player=i;
                for j=1:app.start_hand
                    [Player.cards(j),app.deck]=pick_card(app.deck);
                end
                if real_maker(i)=="Off"
                    Player.is_real= true; 
                else
                    Player.is_real= false;
                end
                app.players{i}=Player;
            end
            clear Player i j


            % creats the first card on the disposed pile
            app.card_on_top=0;
            while (app.card_on_top<100)
                [app.card_on_top,app.deck]=pick_card(app.deck);
                if (mod(app.card_on_top,100) <1 || mod(app.card_on_top,100) >9 ||mod(app.card_on_top,100)==2  )
                    app.deck=[app.deck app.card_on_top];
                    app.card_on_top=0;

                end
            end

            num_cards_disp(app);
            current_turn(app);
            app.top_card=card_create(app,app.top_card,app.card_on_top);
            create_hand(app);
        end
        %%

        function screen_maker(app)
            % orders the screen in accordance to which mode is needed.
            % or main menu screen, or game on screen. will be decided based
            % on app.main_menu value
            UIFigureSizeChanged(app)
            if app.main_menu
                app.Label.Text="player 1";
                app.Label.BackgroundColor="none";
                app.Label_2.Text="player 2";
                app.Label_2.BackgroundColor="none";
                app.Label_3.Text="player 3";
                app.Label_3.BackgroundColor="none";
                app.Label_4.Text="player 4";
                app.Label_4.BackgroundColor="none";
                create=["off","on"];
                app.end_taki.Visible=create(1);
            else
                create=["on","off"];
            end
            % create order changes based on main_menu and is used to create the desired state
            app.more_cards.Text=char(8680);
            app.return_cards.Text=char(8678);
            app.draw_pile.Visible=create(1);
            app.top_card.Visible=create(1);
            app.more_cards.Visible=create(1);
            app.computerSwitch_2.Visible=create(2);
            app.computerSwitch_3.Visible=create(2);
            app.computerSwitch_4.Visible=create(2);
            app.computerSwitchLabel.Visible=create(2);
            app.computerSwitch_2Label.Visible=create(2);
            app.computerSwitch_3Label.Visible=create(2);
            app.players_num.Visible=create(2);
            app.Start.Visible=create(2);
            app.return_cards.Visible=create(1);
            app.cards_view.Visible=create(1);
            app.numberofplayersLabel.Visible=create(2);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this function will call the creation of the game, starting
            % from the main menu

            app.main_menu= true;
            screen_maker(app)
            
            
        end

        % Button pushed function: return_cards
        function return_cardsPushed(app, event)
            % in case the left button of cards selection view is pressed,
            % will move the cards back until the begining of cards

            currentPos = app.cards_view.Position;
            if currentPos(1)==1
                return
            end
            scrollStep = 40; % how big are the steps, needs to  be the same(*-1) in more_cardsPushed
            app.cards_view.Position(1) = currentPos(1) + scrollStep;

        end

        % Button pushed function: more_cards
        function more_cardsPushed(app, event)
            % in case of right button of cards selection is pressed, will
            % move the cards until player can see last card completly.

            currentPos = app.cards_view.Position;
            screen=app.UIFigure.Position;
            cards_fit=screen(3)/80;
            max_point=(length(app.players{app.current_player}.cards)-cards_fit)*-80+1;%(n-9.5)*-40(9.5 how meny fit the screen)
            if currentPos(1)<=max_point
                return
            end
            scrollStep = -40; % how big are the steps, needs to  be the same(*-1) in more_cardsPushed
            app.cards_view.Position(1) = currentPos(1) + scrollStep;

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % handles the location of everything on the GUI screen. will be
            % called on startup and when screen size changes. so everything
            % fits accordignly.
            % inputs:
            %       app- uses all GUI properties
            %       event- happens only on startup and screen size change

            position = app.UIFigure.Position;
            app.more_cards.Position(1)=position(3)-100;
            app.cards_view.Position(1)=1;
            app.top_card.Position=[position(3)/2-10,position(4)/2,80,131];
            app.computerSwitch_4.Position=[position(3)-100,position(4)/2,45,20];
            app.computerSwitch_3Label.Position=[position(3)-105,(position(4)/2)-20,55,22];
            app.computerSwitch_3.Position=[position(3)/2,position(4)-70,45,20];
            app.computerSwitch_2Label.Position=[position(3)/2,position(4)-90,55,22];
            app.computerSwitch_2.Position=[100,position(4)/2,45,20];
            app.computerSwitchLabel.Position=[100,position(4)/2-20,55,22];
            app.players_num.Position=[position(3)/2+30,position(4)/2,100,23];
            app.numberofplayersLabel.Position=[position(3)/2-80,position(4)/2,104,22];
            app.Start.Position=[position(3)/2,position(4)/2-70,100,50];
            app.Label_4.Position=[position(3)-110,position(4)/2,60,60];
            app.Label_3.Position=[position(3)/2,position(4)-70,60,60];
            app.Label_2.Position=[100,position(4)/2,60,60];
            app.draw_pile.Position=[position(3)/2-125,position(4)/2+55,100,30];
            app.end_taki.Position=[position(3)/2-15,150,90,32];
            %changes the location of label of player one accordignly to current screen
            if app.main_menu
                app.Label.Position=[position(3)/2,100,60,60];
            else
                app.Label.Position=[position(3)/2+100,160,60,60];
            end

        end

        % Selection changed function: cards_view
        function cards_viewSelectionChanged(app, event)
            % handels a card pressed current human player. this app will
            % add the card to the disposed card. activate it. and act
            % acordingly. also handels colour selection. and 
            
            selectedButton = app.cards_view.SelectedObject;
            card_choosed=str2double(selectedButton.Tag);
            % possible_options will include the cards that can be played

            % if a +2 is in play
            if app.number_of_twos==0
                possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top)); 
            % non +2
            else
                possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top,"two")); 
            end
            % if taki is in play
            if app.taki_card
                app.end_taki.Visible="on";
                possible_options=intersect(app.players{app.current_player}.cards,correct_cards(app.card_on_top,"taki")); 
            end

            % if the player needs to choose a colour
            if app.card_on_top==1
                possible_options=(1:4)*100;
            end
            if app.card_on_top==2
                card_choosed=card_choosed+13;
                possible_options=(1:4)*100+13;
            end
            
            % card cant be played on the current disposed card
            if ~ismember(card_choosed,possible_options)
                return
            end

            app.top_card=card_create(app,app.top_card,card_choosed);
            app.card_on_top=card_choosed;
            % remove card from current player hand
            if ~app.super_taki
                app.players{app.current_player}.cards(find(app.players{app.current_player}.cards==app.card_on_top,1))=[];
            else
                app.super_taki=false;
            end
            create_hand(app);
            num_cards_disp(app)

            win_check(app)
            if app.main_menu
                return
            end
            if ~app.taki_card
                card_was_choosen(app);
            end
            if app.players{app.current_player}.is_real==0
                non_human_turns(app)
            elseif app.card_on_top~=1 && app.card_on_top~=2
                create_hand(app);
            end
            
        end

        % Button pushed function: draw_pile
        function draw_pileButtonPushed(app, event)
            % handles draw pile button pushed, letting the user draw a card
            % when he wants.
            
            % cant draw after taki or super taki is played
            if app.taki_card || app.super_taki
                return
            end
            % if +2 is in play, draws the right amount, and if not draws 1
            if mod(app.card_on_top,100)==2 && app.number_of_twos~=0
                for i=1:(2*app.number_of_twos)
                    [temp,app.deck]=pick_card(app.deck);
                    app.players{app.current_player}.cards=[app.players{app.current_player}.cards, temp];
                    app.number_of_twos=0;
                end
            else
                [temp,app.deck]=pick_card(app.deck);
                app.players{app.current_player}.cards=[app.players{app.current_player}.cards, temp];
            end
            create_hand(app);
            num_cards_disp(app)
            % next players turn creation
            app.current_player = next_player(app.current_player,app.direction,app.number_of_players);
            current_turn(app)
            if app.players{app.current_player}.is_real==0
                non_human_turns(app)
            elseif app.card_on_top~=1 && app.card_on_top~=2
                create_hand(app);
            end

            
        end

        % Button pushed function: end_taki
        function end_takiButtonPushed(app, event)
            % terminates a taki streak. the this button will only show up
            % when a human tai is in play, letting him end his turn. letting next
            % player turn after current player
            
            app.taki_card=false;
            if mod(app.card_on_top,100)~=13
                card_was_choosen(app);
            else
                app.current_player = next_player(app.current_player,app.direction,app.number_of_players);
            end
            num_cards_disp(app)
            current_turn(app)
            app.end_taki.Visible="off";
            if app.players{app.current_player}.is_real==0
                non_human_turns(app)
            elseif app.card_on_top~=1 && app.card_on_top~=2
                create_hand(app);
            end

        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            % the button only shows up on main menu. when pressed will
            % start the game, changing the screen at the same time
            app.main_menu=false;
            screen_maker(app)
            game_stratup(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.cards_view = uibuttongroup(app.UIFigure);
            app.cards_view.SelectionChangedFcn = createCallbackFcn(app, @cards_viewSelectionChanged, true);
            app.return_cards = uibutton(app.UIFigure, 'push');
            app.return_cards.ButtonPushedFcn = createCallbackFcn(app, @return_cardsPushed, true);
            app.return_cards.Text = '';
            app.more_cards = uibutton(app.UIFigure, 'push');
            app.more_cards.ButtonPushedFcn = createCallbackFcn(app, @more_cardsPushed, true);
            app.more_cards.Text = '';
            app.top_card = uibutton(app.UIFigure, 'push');
            app.draw_pile = uibutton(app.UIFigure, 'push');
            app.draw_pile.ButtonPushedFcn = createCallbackFcn(app, @draw_pileButtonPushed, true);
            app.draw_pile.Text = 'Draw';
            app.end_taki = uibutton(app.UIFigure, 'push');
            app.end_taki.ButtonPushedFcn = createCallbackFcn(app, @end_takiButtonPushed, true);
            app.end_taki.Visible = 'off';
            app.end_taki.Text = 'End taki';
            app.Label = uilabel(app.UIFigure);
            app.Label.Tag = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Tag = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Tag = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tag = '4';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'Start';
            app.numberofplayersLabel = uilabel(app.UIFigure);
            app.numberofplayersLabel.Text = 'number of players:';
            app.players_num = uispinner(app.UIFigure);
            app.players_num.Limits = [2 4];
            app.players_num.Value = 4;
            app.computerSwitchLabel = uilabel(app.UIFigure);
            app.computerSwitchLabel.Text = 'computer';
            app.computerSwitch_2 = uiswitch(app.UIFigure, 'slider');
            app.computerSwitch_2.Value = 'On';
            app.computerSwitch_2Label = uilabel(app.UIFigure);
            app.computerSwitch_2Label.Text = 'computer';
            app.computerSwitch_3 = uiswitch(app.UIFigure, 'slider');
            app.computerSwitch_3.Value = 'On';
            app.computerSwitch_3Label = uilabel(app.UIFigure);
            app.computerSwitch_3Label.Text = 'computer';
            app.computerSwitch_4 = uiswitch(app.UIFigure, 'slider');
            app.computerSwitch_4.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Taki_game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end